classdef data_visualizer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRacing2024DataVisualizerUIFigure  matlab.ui.Figure
        DRIVERDATAVISUALIZATIONTOOLLabel  matlab.ui.control.Label
        Image               matlab.ui.control.Image
        upload_log_btn      matlab.ui.control.Button
        reformat_file_chk   matlab.ui.control.CheckBox
        file_lbl            matlab.ui.control.Label
        run_visualizer_btn  matlab.ui.control.Button
    end

    
    properties (Access = private)
        file = "" % Description
        location = "" % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: run_visualizer_btn
        function run_visualizer(app, event)
            if get(app.reformat_file_chk, 'Value') == 1
                disp('Reformatting log data...')

                % These are the variables we want to keep in the reformatted file
                % Make sure 'variables_select' has no carriage returns (\r)!!!!
                relevant_vars = fileread('variables_relevant.txt');
                relevant_vars = strsplit(relevant_vars, '\n');
                
                % readtable replaces [ ] / with _ and removes whitespace
                relevant_vars = strrep(relevant_vars, '[', '_');
                relevant_vars = strrep(relevant_vars, ']', '_');
                relevant_vars = strrep(relevant_vars, '/', '_');
                relevant_vars = strrep(relevant_vars, ' ', '');
                
                data = readtable([app.location, app.file]);
                data = data(:,relevant_vars);
                
                disp("Writing to new file...")
                % create new file with same name + ddt_
                app.file = strcat("ddt_", app.file);
                writetable(data, strcat(app.location, app.file));
                disp('Finished reformatting')
            end
        end

        % Button pushed function: upload_log_btn
        function upload_file(app, event)
            [f, l] = uigetfile('*.txt');
            if isequal(f, 0)
                return
            else
                app.file = f;
                app.location = l;
                %app.upload_log_btn.Text = app.file;
                app.file_lbl.Text = app.file; % strcat(app.location, app.file);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MRacing2024DataVisualizerUIFigure = uifigure('Visible', 'off');
            app.MRacing2024DataVisualizerUIFigure.Name = 'MRacing 2024 Data Visualizer';
            app.run_visualizer_btn = uibutton(app.MRacing2024DataVisualizerUIFigure, 'push');
            app.run_visualizer_btn.ButtonPushedFcn = createCallbackFcn(app, @run_visualizer, true);
            app.run_visualizer_btn.Text = 'Run Visualizer';
            app.file_lbl = uilabel(app.MRacing2024DataVisualizerUIFigure);
            app.file_lbl.Tooltip = {''};
            app.file_lbl.Text = 'No file selected';
            app.reformat_file_chk = uicheckbox(app.MRacing2024DataVisualizerUIFigure);
            app.reformat_file_chk.Text = 'Reformat file';
            app.upload_log_btn = uibutton(app.MRacing2024DataVisualizerUIFigure, 'push');
            app.upload_log_btn.ButtonPushedFcn = createCallbackFcn(app, @upload_file, true);
            app.upload_log_btn.Text = 'Upload log file';
            app.Image = uiimage(app.MRacing2024DataVisualizerUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'app_resources', 'mracing_logo.png');
            app.DRIVERDATAVISUALIZATIONTOOLLabel = uilabel(app.MRacing2024DataVisualizerUIFigure);
            app.DRIVERDATAVISUALIZATIONTOOLLabel.Text = 'DRIVER DATA VISUALIZATION TOOL';
            % Show the figure after all components are created
            app.MRacing2024DataVisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_visualizer_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRacing2024DataVisualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRacing2024DataVisualizerUIFigure)
        end
    end
end